classdef SCUTGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        Running_ModeButtonGroup        matlab.ui.container.ButtonGroup
        Long_Veloc_RefButton           matlab.ui.control.ToggleButton
        IncreasingSteerButton          matlab.ui.control.ToggleButton
        SweptSineButton                matlab.ui.control.ToggleButton
        Button4                        matlab.ui.control.ToggleButton
        Button5                        matlab.ui.control.ToggleButton
        Button6                        matlab.ui.control.ToggleButton
        Control_AlgorithumButtonGroup  matlab.ui.container.ButtonGroup
        EDSButton                      matlab.ui.control.ToggleButton
        BERSButton                     matlab.ui.control.ToggleButton
        TCSButton                      matlab.ui.control.ToggleButton
        EF20Button                     matlab.ui.control.ToggleButton
        Run_Button                     matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        Reduction_Gear_BoxPanel        matlab.ui.container.Panel
        GainFromLabel                  matlab.ui.control.Label
        GainFromEditField              matlab.ui.control.NumericEditField
        toEditFieldLabel               matlab.ui.control.Label
        toEditField                    matlab.ui.control.NumericEditField
        Step4EditFieldLabel            matlab.ui.control.Label
        Step4EditField                 matlab.ui.control.NumericEditField
        SimulateButton                 matlab.ui.control.Button
        PauseSwitchLabel               matlab.ui.control.Label
        PauseSwitch                    matlab.ui.control.Switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Run_Button
        function Run_ButtonPushed(app, event)
            if(exist("RG","var") == 0 || exist("AV,""var") == 0)
                assignin("base","RG",0),assignin("base","AV",0),set_param("SCUTERacing_Model/Visualization/SetForRgg_Block","commented","off");
                set_param("SCUTERacing_Model/Reference Generator/RealRunData/SetForRgg_Block2","commented","on");
            end
            load_system("main\Chassis\Driveline.slx");
            set_param("Driveline/Trainsmission/Driveline Input Interface/Motor Gearbox Ratio_L","Gain","4");
            set_param("Driveline/Trainsmission/Driveline Input Interface/Motor Gearbox Ratio_R","Gain","4");
            set_param("SCUTERacing_Model/Visualization/Vehicle XY Plotter","commented","off");
            sim("SCUTERacing_Model");
        end

        % Selection changed function: Running_ModeButtonGroup
        function Running_ModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.Running_ModeButtonGroup.SelectedObject;
            str=get(selectedButton,'tag');
            set_param("SCUTERacing_Model/Visualization/SetForRgg_Block","commented","on");
            set_param("SCUTERacing_Model/Visualization/Vehicle XY Plotter","commented","off");
            switch str
                case '0'
                    RG=0,assignin("base","RG",0),set_param("SCUTERacing_Model","Stoptime","10"),
                   set_param("SCUTERacing_Model/Visualization/SetForRgg_Block","commented","off");
                case '1'
                    RG=1,assignin("base","RG",1),set_param("SCUTERacing_Model","Stoptime","60");
                case '2'
                    RG=2,assignin("base","RG",2),set_param("SCUTERacing_Model","Stoptime","60");
                case '3'
                    RG=3,assignin("base","RG",3),set_param("SCUTERacing_Model","Stoptime","60");
                case '4'
                    RG=4,assignin("base","RG",4),set_param("SCUTERacing_Model","Stoptime","60");
                case '5'
                    RG=5,assignin("base","RG",5),set_param("SCUTERacing_Model","Stoptime","5000");
                    set_param("SCUTERacing_Model/Reference Generator/RealRunData/SetForRgg_Block2","commented","off");
                otherwise 
                     RG=0,assignin("base","RG",0),set_param("SCUTERacing_Model","Stoptime","10"),
                     set_param("SCUTERacing_Model/Visualization/SetForRgg_Block","commented","off");%默认是75m直线加速
                    
            end
        end

        % Selection changed function: Control_AlgorithumButtonGroup
        function Control_AlgorithumButtonGroupSelectionChanged(app, event)
            selectedButton = app.Control_AlgorithumButtonGroup.SelectedObject;
            str1 = get(selectedButton,'tag');
            switch str1
                case '0'
                    assignin("base","AV",0);
                case '1'
                    assignin("base","AV",1);
                case '2'
                    assignin("base","AV",2);
                case "3"
                    assignin("base","AV",3);
                otherwise
                    assignin("base","AV",0);
            end
        end

        % Value changed function: GainFromEditField
        function GainFromEditFieldValueChanged(app, event)
            value = app.GainFromEditField.Value;
            assignin("base","start_rgg",value);
        end

        % Value changed function: toEditField
        function toEditFieldValueChanged(app, event)
            value = app.toEditField.Value;
            assignin("base","end_rgg",value);
        end

        % Value changed function: Step4EditField
        function Step4EditFieldValueChanged(app, event)
            value = app.Step4EditField.Value;
            assignin("base","step",value);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            assignin("base","RG",0);
            assignin("base","AV",2);
            set_param("SCUTERacing_Model/Visualization/Vehicle XY Plotter","commented","on");
            run("Reduction_Gear_Simulate.m");
%             start_rgg = evalin("base","start_rgg");
%             end_rgg = evalin("base","end_rgg");
%             step = evalin("base","step");
%             rgg = [start_rgg,end_rgg];
        end

        % Value changed function: PauseSwitch
        function PauseSwitchValueChanged(app, event)
            global GUIStopFlag
            value = app.PauseSwitch.Value;
            
            switch value
                case "Off"
                    GUIStopFlag = 0;assignin("base","GUIStopFlag",0);
                case "On"
                    GUIStopFlag = 1;assignin("base","GUIStopFlag",1);
                otherwise
                    GUIStopFlag = 0;assignin("base","GUIStopFlag",0);
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Running_ModeButtonGroup = uibuttongroup(app.Panel);
            app.Running_ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Running_ModeButtonGroupSelectionChanged, true);
            app.Running_ModeButtonGroup.Title = 'Running_Mode';
            app.Long_Veloc_RefButton = uitogglebutton(app.Running_ModeButtonGroup);
            app.Long_Veloc_RefButton.Tag = '0';
            app.Long_Veloc_RefButton.Text = 'Long_Veloc_Ref';
            app.Long_Veloc_RefButton.Value = true;
            app.IncreasingSteerButton = uitogglebutton(app.Running_ModeButtonGroup);
            app.IncreasingSteerButton.Tag = '1';
            app.IncreasingSteerButton.Text = 'IncreasingSteer';
            app.SweptSineButton = uitogglebutton(app.Running_ModeButtonGroup);
            app.SweptSineButton.Tag = '2';
            app.SweptSineButton.Text = 'SweptSine';
            app.Button4 = uitogglebutton(app.Running_ModeButtonGroup);
            app.Button4.Tag = '3';
            app.Button4.Text = 'SineWithDwell';
            app.Button5 = uitogglebutton(app.Running_ModeButtonGroup);
            app.Button5.Tag = '4';
            app.Button5.Text = 'ConstantRadius';
            app.Button6 = uitogglebutton(app.Running_ModeButtonGroup);
            app.Button6.Tag = '5';
            app.Button6.Text = 'RealRunData';
            app.Control_AlgorithumButtonGroup = uibuttongroup(app.Panel);
            app.Control_AlgorithumButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Control_AlgorithumButtonGroupSelectionChanged, true);
            app.Control_AlgorithumButtonGroup.Title = 'Control_Algorithum';
            app.EDSButton = uitogglebutton(app.Control_AlgorithumButtonGroup);
            app.EDSButton.Tag = '2';
            app.EDSButton.Text = 'EDS';
            app.EDSButton.Value = true;
            app.BERSButton = uitogglebutton(app.Control_AlgorithumButtonGroup);
            app.BERSButton.Tag = '1';
            app.BERSButton.Text = 'BERS';
            app.TCSButton = uitogglebutton(app.Control_AlgorithumButtonGroup);
            app.TCSButton.Tag = '0';
            app.TCSButton.Text = 'TCS';
            app.EF20Button = uitogglebutton(app.Control_AlgorithumButtonGroup);
            app.EF20Button.Tag = '3';
            app.EF20Button.Text = 'EF20';
            app.Run_Button = uibutton(app.Panel, 'push');
            app.Run_Button.ButtonPushedFcn = createCallbackFcn(app, @Run_ButtonPushed, true);
            app.Run_Button.Text = 'Run_Button';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'E-Future_Fixed.jpg';
            app.Reduction_Gear_BoxPanel = uipanel(app.UIFigure);
            app.Reduction_Gear_BoxPanel.Title = 'Reduction_Gear_Box';
            app.GainFromLabel = uilabel(app.Reduction_Gear_BoxPanel);
            app.GainFromLabel.Text = 'Gain: From';
            app.GainFromEditField = uieditfield(app.Reduction_Gear_BoxPanel, 'numeric');
            app.GainFromEditField.ValueChangedFcn = createCallbackFcn(app, @GainFromEditFieldValueChanged, true);
            app.toEditFieldLabel = uilabel(app.Reduction_Gear_BoxPanel);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.Reduction_Gear_BoxPanel, 'numeric');
            app.toEditField.ValueChangedFcn = createCallbackFcn(app, @toEditFieldValueChanged, true);
            app.Step4EditFieldLabel = uilabel(app.Reduction_Gear_BoxPanel);
            app.Step4EditFieldLabel.Text = 'Step(>4)';
            app.Step4EditField = uieditfield(app.Reduction_Gear_BoxPanel, 'numeric');
            app.Step4EditField.ValueChangedFcn = createCallbackFcn(app, @Step4EditFieldValueChanged, true);
            app.SimulateButton = uibutton(app.Reduction_Gear_BoxPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.PauseSwitchLabel = uilabel(app.Reduction_Gear_BoxPanel);
            app.PauseSwitchLabel.Text = 'Pause Switch';
            app.PauseSwitch = uiswitch(app.Reduction_Gear_BoxPanel, 'slider');
            app.PauseSwitch.ValueChangedFcn = createCallbackFcn(app, @PauseSwitchValueChanged, true);
            app.PauseSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCUTGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end